classdef userInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ZdrojovedataTab                 matlab.ui.container.Tab
        ZobrazenieViacPanel             matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        VytvormodelInterpButton         matlab.ui.control.Button
        VytvormodelVButton              matlab.ui.control.Button
        DostupnesadyVListBox            matlab.ui.control.ListBox
        DostupnesadysnimokListBox_2Label  matlab.ui.control.Label
        ZobrazenieJednaPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        VytvormodelJButton              matlab.ui.control.Button
        DostupnesadyJListBox            matlab.ui.control.ListBox
        DostupnesadysnimokListBoxLabel  matlab.ui.control.Label
        ZdrojedatDropDownLabel          matlab.ui.control.Label
        ZdrojedatDropDown               matlab.ui.control.DropDown
        ZobrazenieTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        RezimprojekcieButtonGroup       matlab.ui.container.ButtonGroup
        OrthographicButton_2            matlab.ui.control.RadioButton
        PerspectiveButton               matlab.ui.control.RadioButton
        MaskThresholdSpinner            matlab.ui.control.Spinner
        InterpolaciaSpinner             matlab.ui.control.Spinner
        InterpolaciaSpinnerLabel        matlab.ui.control.Label
        MaskThresholdSlider             matlab.ui.control.Slider
        MaskovacithresholdSliderLabel   matlab.ui.control.Label
        MaskMultiplier                  matlab.ui.control.Slider
        VoxelovymultiplierLabel         matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        StredGroup                      matlab.ui.container.TabGroup
        ZobModTab                       matlab.ui.container.Tab
        ViewerPanel                     matlab.ui.container.Panel
        Viewer                          matlab.ui.control.UIAxes
        ZobZarTab                       matlab.ui.container.Tab
        ZobrPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ZobrAx                          matlab.ui.control.UIAxes
        ZobrCo                          matlab.ui.control.UIAxes
        ZobrSa                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        XYrotSliderLabel                matlab.ui.control.Label
        XYrotSlider                     matlab.ui.control.Slider
        ZrotSliderLabel                 matlab.ui.control.Label
        ZrotSlider                      matlab.ui.control.Slider
        ZAPVYPButton                    matlab.ui.control.StateButton
        PrehliadaniepovrstvachLabel     matlab.ui.control.Label
        VrstvyPanel                     matlab.ui.container.Panel
        VrstvyLayout                    matlab.ui.container.GridLayout
        XHSlider                        matlab.ui.control.Slider
        XSliderLabel                    matlab.ui.control.Label
        YLSlider                        matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        ZLSlider                        matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        XLSlider                        matlab.ui.control.Slider
        YHSlider                        matlab.ui.control.Slider
        ZHSlider                        matlab.ui.control.Slider
        Stav                            matlab.ui.control.Label
        Toolbar                         matlab.ui.container.Toolbar
        loadSourceDataShortcut          matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        sourceData % vstupne data
        nahlady % Description
    end
    
    properties (Access = public)
        modelSingle % Description
        modelMultiple % Description
        zobrazenie % vsetko co sa tyka zobrazovacieho okna
        zobrazenieVlastnosti % Description
        zobrazenieViewer % Description
        zobrazenieModel % Description
    end
    
    methods (Access = private)
        
        function singleOrMultipleSwitch(app)
            if contains(lower(app.ZdrojedatDropDown.Value),'jeden')
               app.ZobrPanel.Enable='off';
               app.ZobrazenieJednaPanel.Visible='on';
               app.ZobrazenieJednaPanel.Enable='on';
               app.ZobrazenieViacPanel.Visible='off';
               app.ZobrazenieViacPanel.Enable='off';
           else
               app.ZobrPanel.Enable='on';
               app.ZobrazenieJednaPanel.Visible='off';
               app.ZobrazenieJednaPanel.Enable='off';
               app.ZobrazenieViacPanel.Visible='on';
               app.ZobrazenieViacPanel.Enable='on';
           end
        end
        
        function drawRelevant(app)
            tab=app.StredGroup.SelectedTab.Title;
            app.Stav.Text="Prekreslujem zobrazenie...";
            if contains(lower(tab),'mod')
                app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold);
                if contains(lower(app.zobrazenieVlastnosti.pohlad),'ortho')
                    camproj(app.Viewer,'orthographic');
                elseif contains(lower(app.zobrazenieVlastnosti.pohlad),'persp')
                    camproj(app.Viewer,'perspective');
                end
            elseif contains(lower(tab),'zar')
                %axial
                modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold);
                %coronal
                modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold);
                %sagittal
                modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold);
                if contains(lower(app.zobrazenieVlastnosti.pohlad),'ortho')
                    camproj(app.ZobrAx,'orthographic');
                    camproj(app.ZobrCo,'orthographic');
                    camproj(app.ZobrSa,'orthographic');
                elseif contains(lower(app.zobrazenieVlastnosti.pohlad),'persp')
                    camproj(app.ZobrAx,'perspective');
                    camproj(app.ZobrCo,'perspective');
                    camproj(app.ZobrSa,'perspective');
                end
            end
            app.Stav.Text="Zobrazenie prekreslene!";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           [sourceContent,app.sourceData.Path,~]=loadSourceData(); 
           app.DostupnesadyJListBox.Items = {sourceContent(:).name};
           app.DostupnesadyVListBox.Items = {sourceContent(:).name};
           app.zobrazenieVlastnosti.sample=app.InterpolaciaSpinner.Value;
           app.zobrazenieVlastnosti.threshold = app.MaskThresholdSlider.Value;
           app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
           app.zobrazenieVlastnosti.pohlad='orthographic';
           app.nahlady=app.ZobrAx;
           app.InterpolaciaSpinner.Value=app.zobrazenieVlastnosti.sample;
           singleOrMultipleSwitch(app);
           app.Stav.Text="Cakam na vstup...";
        end

        % Button pushed function: VytvormodelJButton
        function VytvormodelJButtonPushed(app, event)
            app.Stav.Text="Nacitavam model...";
            app.modelSingle.main=makeModelSingle(app.sourceData.Path,app.DostupnesadyJListBox.Value,app.DostupnesadyJListBox.Value);
            app.Stav.Text="Zobrazujem model...";
            app.zobrazenieModel=app.modelSingle.main;
            app.zobrazenieViewer=modelVisualizer(app.zobrazenieModel,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.Viewer,app.zobrazenieVlastnosti.threshold);
            app.Stav.Text="Model zobrazeny!";
        end

        % Value changed function: InterpolaciaSpinner
        function InterpolaciaSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.sample = app.InterpolaciaSpinner.Value;
            drawRelevant(app);
        end

        % Value changed function: ZdrojedatDropDown
        function ZdrojedatDropDownValueChanged(app, event)
           singleOrMultipleSwitch(app);
        end

        % Value changed function: MaskThresholdSlider
        function MaskThresholdSliderValueChanged(app, event)
            app.zobrazenieVlastnosti.threshold = app.MaskThresholdSlider.Value;
            app.MaskThresholdSpinner.Value = app.zobrazenieVlastnosti.threshold;
            drawRelevant(app);
        end

        % Value changed function: MaskMultiplier
        function MaskMultiplierValueChanged(app, event)
            app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
            drawRelevant(app);
        end

        % Button pushed function: VytvormodelVButton
        function VytvormodelVButtonPushed(app, event)
            if length(app.DostupnesadyVListBox.Value)~=3
                fprintf("Pre uspesne zostavenie rezu potrebujete mat vybrane tri rezy, ktorych nazvy obsahuju axial, coronal a sagittal.\nStlacte akukolvek klavesu pre pokracovanie.\n");
                pause;
            else
                app.Stav.Text="Nacitavam modely...";
                [app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittal,app.modelMultiple.sagittalAddress] = makeModelMultiple(app.sourceData.Path,app.DostupnesadyVListBox.Value,app.DostupnesadyVListBox.Value);
%                 figure(app.ZobrAx)
                app.Stav.Text="Zobrazujem modely...";
                %axial
                modelVisualizer(app.modelMultiple.axial,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrAx,app.zobrazenieVlastnosti.threshold);
                %coronal
                modelVisualizer(app.modelMultiple.coronal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrCo,app.zobrazenieVlastnosti.threshold);
                %sagittal
                modelVisualizer(app.modelMultiple.sagittal,app.zobrazenieVlastnosti.sample,app.zobrazenieVlastnosti.multiplier,app.ZobrSa,app.zobrazenieVlastnosti.threshold);
                app.Stav.Text="Modely zobrazene!";
            end
        end

        % Button pushed function: VytvormodelInterpButton
        function VytvormodelInterpButtonPushed(app, event)
            app.Stav.Text="Vytvaram interpolovany model...";
            app.modelMultiple.main=spatialMatrixInterp(app.modelMultiple.axial,app.modelMultiple.coronal,app.modelMultiple.sagittal,app.modelMultiple.coronalAddress,app.modelMultiple.sagittalAddress,true);
            app.Stav.Text="Zobrazujem model...";
            app.zobrazenieModel=app.modelMultiple.main;
            drawRelevant(app);
            app.Stav.Text="Model zobrazeny!";
        end

        % Value changed function: MaskThresholdSpinner
        function MaskThresholdSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.threshold = app.MaskThresholdSpinner.Value;
            app.MaskThresholdSlider.Value = app.zobrazenieVlastnosti.threshold;
            drawRelevant(app);
        end

        % Selection changed function: RezimprojekcieButtonGroup
        function RezimprojekcieButtonGroupSelectionChanged(app, event)
            app.zobrazenieVlastnosti.pohlad = app.RezimprojekcieButtonGroup.SelectedObject.Text;
            drawRelevant(app);
        end

        % Value changing function: XYrotSlider
        function XYrotSliderValueChanging(app, event)
            changingValue = event.Value;
            camorbit(app.Viewer,changingValue,0,'data',[0 0 1]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ZdrojovedataTab = uitab(app.TabGroup);
            app.ZdrojovedataTab.Title = 'Zdrojove data';
            app.ZobrazenieViacPanel = uipanel(app.ZdrojovedataTab);
            app.ZobrazenieViacPanel.Title = 'Zobrazenie z viacerých sád snímok';
            app.GridLayout3 = uigridlayout(app.ZobrazenieViacPanel);
            app.VytvormodelInterpButton = uibutton(app.GridLayout3, 'push');
            app.VytvormodelInterpButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelInterpButtonPushed, true);
            app.VytvormodelInterpButton.Text = 'Vytvor interpolovany model';
            app.VytvormodelVButton = uibutton(app.GridLayout3, 'push');
            app.VytvormodelVButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelVButtonPushed, true);
            app.VytvormodelVButton.Text = 'Vytvor modely';
            app.DostupnesadyVListBox = uilistbox(app.GridLayout3);
            app.DostupnesadyVListBox.Multiselect = 'on';
            app.DostupnesadyVListBox.Value = {'Item 1'};
            app.DostupnesadysnimokListBox_2Label = uilabel(app.GridLayout3);
            app.DostupnesadysnimokListBox_2Label.Text = 'Dostupne sady snimok';
            app.ZobrazenieJednaPanel = uipanel(app.ZdrojovedataTab);
            app.ZobrazenieJednaPanel.Title = 'Zobrazenie z jednej sady snimok';
            app.GridLayout4 = uigridlayout(app.ZobrazenieJednaPanel);
            app.VytvormodelJButton = uibutton(app.GridLayout4, 'push');
            app.VytvormodelJButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelJButtonPushed, true);
            app.VytvormodelJButton.Text = 'Vytvor model';
            app.DostupnesadyJListBox = uilistbox(app.GridLayout4);
            app.DostupnesadyJListBox.Items = {'aaa'};
            app.DostupnesadyJListBox.Value = {};
            app.DostupnesadysnimokListBoxLabel = uilabel(app.GridLayout4);
            app.DostupnesadysnimokListBoxLabel.Text = 'Dostupne sady snimok';
            app.ZdrojedatDropDownLabel = uilabel(app.ZdrojovedataTab);
            app.ZdrojedatDropDownLabel.Text = 'Zdroje dat';
            app.ZdrojedatDropDown = uidropdown(app.ZdrojovedataTab);
            app.ZdrojedatDropDown.Items = {'jeden', 'viacero'};
            app.ZdrojedatDropDown.ValueChangedFcn = createCallbackFcn(app, @ZdrojedatDropDownValueChanged, true);
            app.ZdrojedatDropDown.Value = 'jeden';
            app.ZobrazenieTab = uitab(app.TabGroup);
            app.ZobrazenieTab.Title = 'Zobrazenie';
            app.GridLayout5 = uigridlayout(app.ZobrazenieTab);
            app.RezimprojekcieButtonGroup = uibuttongroup(app.GridLayout5);
            app.RezimprojekcieButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RezimprojekcieButtonGroupSelectionChanged, true);
            app.RezimprojekcieButtonGroup.Title = 'Rezim projekcie';
            app.OrthographicButton_2 = uiradiobutton(app.RezimprojekcieButtonGroup);
            app.OrthographicButton_2.Text = {'Orthographic'; ''};
            app.OrthographicButton_2.Value = true;
            app.PerspectiveButton = uiradiobutton(app.RezimprojekcieButtonGroup);
            app.PerspectiveButton.Text = {'Perspective'; ''};
            app.MaskThresholdSpinner = uispinner(app.GridLayout5);
            app.MaskThresholdSpinner.Step = 0.05;
            app.MaskThresholdSpinner.Limits = [0.01 1];
            app.MaskThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdSpinnerValueChanged, true);
            app.MaskThresholdSpinner.Value = 0.2;
            app.InterpolaciaSpinner = uispinner(app.GridLayout5);
            app.InterpolaciaSpinner.Step = 2;
            app.InterpolaciaSpinner.Limits = [1 64];
            app.InterpolaciaSpinner.ValueChangedFcn = createCallbackFcn(app, @InterpolaciaSpinnerValueChanged, true);
            app.InterpolaciaSpinner.Value = 8;
            app.InterpolaciaSpinnerLabel = uilabel(app.GridLayout5);
            app.InterpolaciaSpinnerLabel.Text = 'Interpolacia';
            app.MaskThresholdSlider = uislider(app.GridLayout5);
            app.MaskThresholdSlider.Limits = [0.01 1];
            app.MaskThresholdSlider.MajorTicks = [0.01 0.25 0.5 0.75 1];
            app.MaskThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdSliderValueChanged, true);
            app.MaskThresholdSlider.Value = 0.2;
            app.MaskovacithresholdSliderLabel = uilabel(app.GridLayout5);
            app.MaskovacithresholdSliderLabel.Text = 'Maskovaci threshold';
            app.MaskMultiplier = uislider(app.GridLayout5);
            app.MaskMultiplier.Limits = [1 25];
            app.MaskMultiplier.ValueChangedFcn = createCallbackFcn(app, @MaskMultiplierValueChanged, true);
            app.MaskMultiplier.Value = 2.5;
            app.VoxelovymultiplierLabel = uilabel(app.GridLayout5);
            app.VoxelovymultiplierLabel.Text = 'Voxelovy multiplier';
            app.CenterPanel = uipanel(app.GridLayout);
            app.StredGroup = uitabgroup(app.CenterPanel);
            app.ZobModTab = uitab(app.StredGroup);
            app.ZobModTab.Title = '3D zobrazenie modelu';
            app.ViewerPanel = uipanel(app.ZobModTab);
            app.ViewerPanel.Title = '3D Panel';
            app.Viewer = uiaxes(app.ViewerPanel);
            title(app.Viewer, 'Title')
            xlabel(app.Viewer, 'X')
            ylabel(app.Viewer, 'Y')
            zlabel(app.Viewer, 'Z')
            app.ZobZarTab = uitab(app.StredGroup);
            app.ZobZarTab.Title = 'Zobrazenie pre zarovnanie modelov';
            app.ZobrPanel = uipanel(app.ZobZarTab);
            app.GridLayout2 = uigridlayout(app.ZobrPanel);
            app.ZobrAx = uiaxes(app.GridLayout2);
            title(app.ZobrAx, 'Title')
            xlabel(app.ZobrAx, 'X')
            ylabel(app.ZobrAx, 'Y')
            zlabel(app.ZobrAx, 'Z')
            app.ZobrCo = uiaxes(app.GridLayout2);
            title(app.ZobrCo, 'Title')
            xlabel(app.ZobrCo, 'X')
            ylabel(app.ZobrCo, 'Y')
            zlabel(app.ZobrCo, 'Z')
            app.ZobrSa = uiaxes(app.GridLayout2);
            title(app.ZobrSa, 'Title')
            xlabel(app.ZobrSa, 'X')
            ylabel(app.ZobrSa, 'Y')
            zlabel(app.ZobrSa, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.XYrotSliderLabel = uilabel(app.RightPanel);
            app.XYrotSliderLabel.Text = 'XY rot';
            app.XYrotSlider = uislider(app.RightPanel);
            app.XYrotSlider.ValueChangingFcn = createCallbackFcn(app, @XYrotSliderValueChanging, true);
            app.ZrotSliderLabel = uilabel(app.RightPanel);
            app.ZrotSliderLabel.Text = 'Z rot';
            app.ZrotSlider = uislider(app.RightPanel);
            app.ZAPVYPButton = uibutton(app.RightPanel, 'state');
            app.ZAPVYPButton.Text = 'ZAP/VYP';
            app.PrehliadaniepovrstvachLabel = uilabel(app.RightPanel);
            app.PrehliadaniepovrstvachLabel.Text = 'Prehliadanie po vrstvach';
            app.VrstvyPanel = uipanel(app.RightPanel);
            app.VrstvyPanel.Title = 'Vrstvy';
            app.VrstvyLayout = uigridlayout(app.VrstvyPanel);
            app.XHSlider = uislider(app.VrstvyLayout);
            app.XSliderLabel = uilabel(app.VrstvyLayout);
            app.XSliderLabel.Text = 'X';
            app.YLSlider = uislider(app.VrstvyLayout);
            app.YSliderLabel = uilabel(app.VrstvyLayout);
            app.YSliderLabel.Text = 'Y';
            app.ZLSlider = uislider(app.VrstvyLayout);
            app.ZSliderLabel = uilabel(app.VrstvyLayout);
            app.ZSliderLabel.Text = 'Z';
            app.XLSlider = uislider(app.VrstvyLayout);
            app.YHSlider = uislider(app.VrstvyLayout);
            app.ZHSlider = uislider(app.VrstvyLayout);
            app.Stav = uilabel(app.RightPanel);
            app.Stav.Text = 'Stavovy riadok';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.loadSourceDataShortcut = uipushtool(app.Toolbar);
            app.loadSourceDataShortcut.Tooltip = {'Znovu načíta zdroje z priečinka sourceData.'};
            app.loadSourceDataShortcut.Icon = 'icon-mat-refresh.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end